classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        Image_3                 matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        ImportButton            matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        ReconstructRoomButton   matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        UserUpload; % Description
    end
    
    methods (Access = private)   
        % reads files into array
        function readImages(app, folderpath)   
            files = dir(fullfile(folderpath,'*jpg'));
            filename = {files.name};            
            filename = filename(~ismember(filename,{'.','..'}));
            images = cell([length(files) 1]);
            for i =1:length(files)                     
                images{i} = imread(append(folderpath,'/',filename{i}));    
            end
            app.UserUpload = images;
            app.Image.ImageSource = append(folderpath,'/',filename{1});
            app.Image.Visible = "on";
            app.Image_2.ImageSource = append(folderpath,'/',filename{2});
            app.Image_2.Visible = "on";
            app.Image_3.ImageSource = append(folderpath,'/',filename{3});
            app.Image_3.Visible = "on"; 
        end           
        
        function plotRoom(app,pc,model,opacity)
            % Input Parser            
            tic            
            color_floor_ceil = [1 1 1];
            floor = model{1};
            ceil = model{2};
            cuboids = model{3};
            
            P = zeros(8,3,length(cuboids));           
            % idx tells in which order plot should connect dots
            idx = [4 8 5 1 4; 1 5 6 2 1; 2 6 7 3 2;...
                    3 7 8 4 3; 5 8 7 6 5; 1 4 3 2 1]';
            % store the cuboid plot objects
            for i = 1:length(cuboids)    
                P(:,:,i) = getCornerPoints(cuboids{i});
                xc = P(:,1,i);
                yc = P(:,2,i);
                zc = P(:,3,i);                
                patch(xc(idx), yc(idx), zc(idx), 'w', ...
                   'facealpha', opacity, Parent=app.UIAxes);
                view(app.UIAxes,3);    
                hold(app.UIAxes,'on');
            end
            plot(floor,Color=color_floor_ceil,Parent=app.UIAxes);
            plot(ceil,Color=color_floor_ceil,Parent=app.UIAxes);
            %zoom on            
            %axis off;
            %player = pcplayer(pc.XLimits,pc.YLimits,pc.ZLimits,...
            %AxesVisibility='off', BackgroundColor = color_floor_ceil,Parent=app.UIAxes); 
            %view(player,pc); 
            %hold(app.UIAxes,'off');
            toc
            c = uicontrol(app.UIFigure,'Position',[0.1 0.1 0.35 0.65]);
            c.String = 'Show Pointcloud';
            c.Callback = @(src,event) plotButtonPushed(ax);            
            function plotButtonPushed(~,~,ax)    
                ax.Visible = 'off';     
            end
        end  
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            %value = app.FilenameEditField.Value; 
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            path = uigetdir('','Select folder');
            if(~path)
                % user canceled data import
                return
            end
            readImages(app, path);
            app.FilenameEditField.Value = path;  
            figure(app.UIFigure);
        end

        % Button pushed function: ReconstructRoomButton
        function ReconstructRoomButtonPushed(app, event)
            % insert pc and model here
            model = struct2cell(load('testModel.mat'));
            model = model{1};                 
            pc = struct2cell(load('testCloud.mat'));
            pc = pc{1};
            plotRoom(app,pc,model,.7);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ReconstructRoomButton = uibutton(app.UIFigure, 'push');
            app.ReconstructRoomButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructRoomButtonPushed, true);
            app.ReconstructRoomButton.Text = 'Reconstruct Room';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.Visible = 'off';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end